classdef LINA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        compressibleflowcalculatorUIFigure  matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
        iterationdoneEditField       matlab.ui.control.NumericEditField
        iterationdoneEditFieldLabel  matlab.ui.control.Label
        computetimesEditField        matlab.ui.control.NumericEditField
        computetimesEditFieldLabel   matlab.ui.control.Label
        P02P1EditField               matlab.ui.control.NumericEditField
        P02P1EditFieldLabel          matlab.ui.control.Label
        P02P01EditField              matlab.ui.control.NumericEditField
        P02P01EditFieldLabel         matlab.ui.control.Label
        rho2rho1EditField            matlab.ui.control.NumericEditField
        rho2rho1EditFieldLabel       matlab.ui.control.Label
        T2T1EditField                matlab.ui.control.NumericEditField
        T2T1EditFieldLabel           matlab.ui.control.Label
        P2P1EditField_2              matlab.ui.control.NumericEditField
        P2P1EditField_2Label         matlab.ui.control.Label
        M2EditField                  matlab.ui.control.NumericEditField
        M2EditFieldLabel             matlab.ui.control.Label
        findButton                   matlab.ui.control.Button
        AAEditField                  matlab.ui.control.NumericEditField
        AAEditFieldLabel             matlab.ui.control.Label
        aoaEditField                 matlab.ui.control.NumericEditField
        aoaEditFieldLabel            matlab.ui.control.Label
        PoPEditField                 matlab.ui.control.NumericEditField
        PoPEditFieldLabel            matlab.ui.control.Label
        ToTEditField                 matlab.ui.control.NumericEditField
        ToTEditFieldLabel            matlab.ui.control.Label
        Rho0rhoEditField             matlab.ui.control.NumericEditField
        Rho0rhoEditFieldLabel        matlab.ui.control.Label
        MachEditField                matlab.ui.control.NumericEditField
        MachEditFieldLabel           matlab.ui.control.Label
        UITable2                     matlab.ui.control.Table
        table                        matlab.ui.control.Switch
        UITable                      matlab.ui.control.Table
        TypeofnormalmovingshockwaveSwitch  matlab.ui.control.Switch
        TypeofnormalmovingshockwaveSwitchLabel  matlab.ui.control.Label
        Mach3p                       matlab.ui.control.NumericEditField
        M3pV3p                       matlab.ui.control.Switch
        Machref                      matlab.ui.control.NumericEditField
        M2rV2r                       matlab.ui.control.Switch
        T3EditField                  matlab.ui.control.NumericEditField
        T3EditFieldLabel             matlab.ui.control.Label
        P3EditField                  matlab.ui.control.NumericEditField
        P3EditFieldLabel             matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        T2EditFieldLabel             matlab.ui.control.Label
        T2EditField                  matlab.ui.control.NumericEditField
        Switch2_2                    matlab.ui.control.Switch
        Switch2                      matlab.ui.control.Switch
        Switch_3                     matlab.ui.control.Switch
        M2V2                         matlab.ui.control.NumericEditField
        M1V1                         matlab.ui.control.NumericEditField
        computeButton                matlab.ui.control.Button
        Switch_2                     matlab.ui.control.Switch
        Switch                       matlab.ui.control.Switch
        Machv2                       matlab.ui.control.NumericEditField
        P2EditField                  matlab.ui.control.NumericEditField
        P2EditFieldLabel             matlab.ui.control.Label
        MsEditField                  matlab.ui.control.NumericEditField
        OUTPUTLabel                  matlab.ui.control.Label
        REditField                   matlab.ui.control.NumericEditField
        REditFieldLabel              matlab.ui.control.Label
        gammaEditField               matlab.ui.control.NumericEditField
        gammaLabel                   matlab.ui.control.Label
        machshock                    matlab.ui.control.NumericEditField
        P2P1EditField                matlab.ui.control.NumericEditField
        P2P1Label                    matlab.ui.control.Label
        P1EditField                  matlab.ui.control.NumericEditField
        P1EditFieldLabel             matlab.ui.control.Label
        T1EditField                  matlab.ui.control.NumericEditField
        T1EditFieldLabel             matlab.ui.control.Label
        INPUTLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
        value = app.Switch.Value;
        Mach2=app.Switch_2.Value;
        Machs=app.Switch_3.Value;
        % if strcmp(Machs,'Ms')
        %     Ms = app.machshock.Value;
        % 
        % else
        %     Vs = app.machshock.Value;
        % 
        % end
        % 
        % if strcmp(Mach2,'M2')
        %     M2 = app.M2V2.Value;
        % 
        % else
        %     V2 = app.M2V2.Value;
        % 
        % end

        % if strcmp(value,'M1')
        %     M1 = app.M1V1.Value;
        % 
        % else
        %     V1 = app.M1V1.Value;
        % 
        % end
        T1=app.T1EditField.Value;
        P1=app.P1EditField.Value;
        P2P1=app.P2P1EditField.Value;
        gamma= app.gammaEditField.Value;
        R=app.REditField.Value;
        M1=0;
        V1=0;
        M2=0;
        V2=0;
        Ms=0;
        Vs=0;

        switch app.Switch.Value
            case 'M1'
                M1 = app.M1V1.Value;
                f=1;
            case 'V1'
                V1=app.M1V1.Value;
        end

        switch app.Switch_2.Value
            case 'M2'
                M2 = app.M2V2.Value;
            case 'V2'
                V2=app.M2V2.Value;
        end

        switch app.Switch_3.Value
            case 'Ms'
                Ms = app.machshock.Value;
            case 'Vs'
                Vs=app.machshock.Value;
        end
       
      
        % switch true
        %     case ((app.P1EditField.Value~=0)&& (app.T1EditField.Value~=0) && (app.P2P1EditField.Value~=0))
        switch app.TypeofnormalmovingshockwaveSwitch.Value
            case 'normal'
                if ((app.P1EditField.Value~=0)&& (app.T1EditField.Value~=0) && (app.P2P1EditField.Value~=0));
                    [Vs_out,V2_out,T2]=second_case(T1,P2P1,V1,gamma,R);
                    Ms_out=Vs_out./(sqrt(gamma*R*T1));
                    M2_out=V2_out./(sqrt(gamma*R*T2));
                    P2=P1*P2P1;
                    switch app.Switch2.Value
                        case 'Ms'
                            app.MsEditField.Value=Ms_out;
                            app.machshock.Value=Ms_out;
                        case 'Vs'
                            app.MsEditField.Value=Vs_out;
                            app.machshock.Value=Vs_out;
                    end
                    app.P2EditField.Value=P2;
                    app.T2EditField.Value=T2;
                    switch app.Switch2_2.Value
                        case 'M2'
                            app.Machv2.Value=M2_out;
                        case 'V2'
                            app.Machv2.Value=V2_out;
                    end
                elseif((app.M2V2.Value>0)&& (app.T1EditField.Value>0)&&(app.gammaEditField.Value~=0)&&(app.REditField.Value~=0))


                    [Ms_out,Vs_out,P2,T2,M2_out,V2_out,computeTime,iter]=first_case(V1,T1,M2,P1,gamma,R);
                    app.computetimesEditField.Value=computeTime;
                    app.iterationdoneEditField.Value=iter;


                    switch app.Switch2.Value
                        case 'Ms'
                            app.MsEditField.Value=Ms_out;
                            app.machshock.Value=Ms_out;
                        case 'Vs'
                            app.MsEditField.Value=Vs_out;
                            app.machshock.Value=Vs_out;
                    end
                    app.P2EditField.Value=P2;
                    app.T2EditField.Value=T2;
                    switch app.Switch2_2.Value
                        case 'M2'
                            app.Machv2.Value=M2_out;
                        case 'V2'
                            app.Machv2.Value=V2_out;
                    end

                elseif((app.machshock.Value~=0)&&(app.T1EditField.Value>0)&&(app.gammaEditField.Value~=0)&&(app.REditField.Value~=0))
                    [T2,P2,V2_out]=third_case(Vs,gamma,V1,P1,T1,R);
                    app.T2EditField.Value=T2;
                    app.P2EditField.Value=P2;
                    M2_out=V2_out./(sqrt(gamma*R*T2));
                    Ms=Vs./sqrt(gamma*R*T1);
                    switch app.Switch2.Value
                        case 'Ms'
                            app.MsEditField.Value=Ms;
                            app.machshock.Value=Ms;
                        case 'Vs'
                            app.MsEditField.Value=Vs;
                            app.machshock.Value=Vs;
                    end
                    switch app.Switch2_2.Value
                        case 'M2'
                            app.Machv2.Value=M2_out;
                        case 'V2'
                            app.Machv2.Value=V2_out;
                    end
                end

                % switch true
                %     case ((app.P1EditField.Value~=0)&& (app.T1EditField.Value~=0) && (app.P2P1EditField.Value~=0))

            case 'reflected'
                
                switch app.M2rV2r.Value
                    case 'M2r_p'
                        M2r_p = app.Machref.Value;
                    case 'V2r_p'
                        V2r_p=app.Machref.Value;
                end
                switch app.M3pV3p.Value
                    case 'M3_p'
                        M3Pr = app.Mach3p.Value;
                    case 'V3_p'
                        V3Pr=app.Mach3p.Value;
                end
                switch true
                    case (app.P1EditField.Value~=0 && app.T1EditField.Value~=0 && app.P2P1EditField.Value~=0)
                        [P2,T2,P3,T3,Ms,M2_out,M3pr,M2r_p,iter,computeTime]= fourth_case(P1,T1,P2P1,gamma,V1,R);
                        app.computetimesEditField.Value=computeTime;
                        app.iterationdoneEditField.Value=iter;

                        app.T2EditField.Value=T2;
                        app.P2EditField.Value=P2;
                        app.P3EditField.Value=P3;
                        app.T3EditField.Value=T3;
                        Vs=Ms*(sqrt(gamma*R*T1));
                        switch app.Switch2.Value
                            case 'Ms'
                                app.MsEditField.Value=Ms;
                                app.machshock.Value=Ms;
                            case 'Vs'
                                app.MsEditField.Value=Vs;
                                app.machshock.Value=Vs;
                        end
                        V2_out=M2_out*(sqrt(gamma*R*T2));
                        switch app.Switch2_2.Value
                            case 'M2'
                                app.Machv2.Value=M2_out;
                            case 'V2'
                                app.Machv2.Value=V2_out;
                        end
                        V2r_p=M2r_p*(sqrt(gamma*R*T2));
                        switch app.M2rV2r.Value
                            case 'M2r_p'
                                app.Machref.Value= M2r_p  ;
                            case 'V2r_p'
                                app.Machref.Value=V2r_p  ;
                        end
                        V3Pr=M3pr*(sqrt(gamma*R*T3));

                        switch app.M3pV3p.Value
                            case 'M3_p'
                                app.Mach3p.Value=M3Pr;
                            case 'V3_p'
                                app.Mach3p.Value=V3Pr;
                        end
                    case (app.M1V1.Value~=0 && app.M2V2.Value==0 &&app.T1EditField.Value~=0 && app.P1EditField.Value~=0)
                        [P2,T2,M2_out,M1_p,iter,computeTime]=fifth_case(V1,P1,T1,M2,gamma,R);
                        app.computetimesEditField.Value=computeTime;
                        app.iterationdoneEditField.Value=iter;

                        app.T2EditField.Value=T2;
                        app.P2EditField.Value=P2;
                        app.P3EditField.Value=inf;
                        app.T3EditField.Value=inf;
                        V2_out=M2_out*(sqrt(gamma*R*T2));
                        switch app.Switch2_2.Value
                            case 'M2'
                                app.Machv2.Value=M2_out;
                            case 'V2'
                                app.Machv2.Value=V2_out;
                        end
                        M2r_p=mach2(gamma,M1_p);
                        V2r_p=M2r_p*(sqrt(gamma*R*T2));
                        switch app.M2rV2r.Value
                            case 'M2r_p'
                                app.Machref.Value= M2r_p  ;
                            case 'V2r_p'
                                app.Machref.Value=V2r_p  ;
                        end
                        Ms=M1_p;
                        Vs=Ms*(sqrt(gamma*R*T1));
                        switch app.Switch2.Value
                            case 'Ms'
                                app.MsEditField.Value=Ms;
                                app.machshock.Value=Ms;
                            case 'Vs'
                                app.MsEditField.Value=Vs;
                                app.machshock.Value=Vs;
                        end


                end
        end


        %Ms=M1+1;
       %Ms=V1+3;

        end

        % Value changed function: TypeofnormalmovingshockwaveSwitch
        function TypeofnormalmovingshockwaveSwitchValueChanged(app, event)
            value = app.TypeofnormalmovingshockwaveSwitch.Value;
            switch value
                case'normal'
                    app.Image2.Visible='off';
                    app.M2rV2r.Visible='off';
                    app.M3pV3p.Visible='off';
                    app.P3EditField.Visible='off';
                    app.T3EditField.Visible='off';
                    app.T3EditFieldLabel.Visible='off';
                    app.P3EditFieldLabel.Visible='off';
                    app.Machref.Visible='off';
                    app.Mach3p.Visible='off';
                    app.Image.Visible="on";
                    % app.Switch.Visible="on";
                    % app.Switch_2.Visible="on";
                    % app.Switch_3.Visible="on";
                    % app.T1EditField.Visible="on";
                    % app.P1EditField.Visible="on";
                    % app.P2P1EditField.Visible="on";
                    % app.gammaEditField.Visible="on";
                    % app.REditField.Visible="on";
                    % app.Switch2.Visible="on";
                    % app.Switch2_2.Visible="on";
                    % app.P2EditField.Visible="on";
                    % app.T2EditField.Visible="on";
                    % app.MsEditField.Visible="on";
                    % app.Machv2.Visible="on";

                case 'reflected'
                    app.Image2.Visible='on';
                    app.M2rV2r.Visible='on';
                    app.M3pV3p.Visible='on';
                    app.P3EditField.Visible='on';
                    app.T3EditField.Visible='on';
                    app.T3EditFieldLabel.Visible='on';
                    app.P3EditFieldLabel.Visible='on';
                    app.Machref.Visible='on';
                    app.Mach3p.Visible='on';
                    app.Image.Visible="off";
                    % app.Switch.Visible="on";
                    % app.Switch_2.Visible="on";
                    % app.Switch_3.Visible="on";
                    % app.T1EditField.Visible="on";
                    % app.P1EditField.Visible="on";
                    % app.P2P1EditField.Visible="on";
                    % app.gammaEditField.Visible="on";
                    % app.REditField.Visible="on";
                    % app.Switch2.Visible="on";
                    % app.Switch2_2.Visible="on";
                    % app.P2EditField.Visible="on";
                    % app.T2EditField.Visible="on";
                    % app.MsEditField.Visible="on";
                    % app.Machv2.Visible="on";

            end


        end

        % Value changed function: table
        function tableValueChanged(app, event)
            value = app.table.Value;
            switch value
                case 'Isentropic tables for gamma=1.4'
                    app.P02P1EditField.Visible="off";
                    app.P02P1EditFieldLabel.Visible="off";
                    app.UITable2.Visible="off";
                    app.M2EditField.Visible="off";
                    app.M2EditFieldLabel.Visible="off";
                    app.P2P1EditField_2.Visible="off";
                    app.P2P1EditField_2Label.Visible="off";
                    app.T2T1EditField.Visible="off";
                    app.T2T1EditFieldLabel.Visible="off";
                    app.rho2rho1EditField.Visible="off";
                    app.rho2rho1EditFieldLabel.Visible="off";
                    app.P02P01EditField.Visible="off";
                    app.P02P01EditFieldLabel.Visible="off";
                    app.AAEditField.Visible="on";
                    app.AAEditFieldLabel.Visible="on";
                    app.aoaEditField.Visible="on";
                    app.aoaEditFieldLabel.Visible="on";
                    app.PoPEditField.Visible="on";
                    app.PoPEditFieldLabel.Visible="on";
                    app.ToTEditField.Visible="on";
                    app.ToTEditFieldLabel.Visible="on";
                    app.Rho0rhoEditField.Visible="on";
                    app.Rho0rhoEditFieldLabel.Visible="on";
                    M = 1.0:0.01:5.5;
                    gamma=1.4;
                    T0_T = 1 + (gamma - 1) ./ 2 .* M.^2;
                    P0_P = (1 + (gamma - 1) ./ 2 .* M.^2).^(gamma ./ (gamma - 1));
                    rho0_rho = (1 + (gamma - 1) ./ 2 .* M.^2).^(1 ./ (gamma - 1));
                    a0_a = (1 + (gamma - 1) ./ 2 .* M.^2).^1/2;
                    A_Astar = 1 ./ M .* (2 ./ (gamma + 1) .* (1 + (gamma - 1) / 2 .* M.^2)).^((gamma + 1) ./ (2 .* (gamma - 1)));
                    T=table(M',T0_T',P0_P',rho0_rho',a0_a',A_Astar');
                    app.UITable.Data=T;
                    app.UITable.ColumnName={'Mach no','To/T','Po/P','rho0/rho','ao/a','A/A*'};
                    
                case 'Normal shock tables for gamma=1.4'
                    app.P02P1EditField.Visible="on";
                    app.P02P1EditFieldLabel.Visible="on";
                    app.UITable2.Visible="on";
                    app.M2EditField.Visible="on";
                    app.M2EditFieldLabel.Visible="on";
                    app.P2P1EditField_2.Visible="on";
                    app.P2P1EditField_2Label.Visible="on";
                    app.T2T1EditField.Visible="on";
                    app.T2T1EditFieldLabel.Visible="on";
                    app.rho2rho1EditField.Visible="on";
                    app.rho2rho1EditFieldLabel.Visible="on";
                    app.P02P01EditField.Visible="on";
                    app.P02P01EditFieldLabel.Visible="on";
                    app.AAEditField.Visible="off";
                    app.AAEditFieldLabel.Visible="off";
                    app.aoaEditField.Visible="off";
                    app.aoaEditFieldLabel.Visible="off";
                    app.PoPEditField.Visible="off";
                    app.PoPEditFieldLabel.Visible="off";
                    app.ToTEditField.Visible="off";
                    app.ToTEditFieldLabel.Visible="off";
                    app.Rho0rhoEditField.Visible="off";
                    app.Rho0rhoEditFieldLabel.Visible="off";
                    
                    gamma = 1.4;
                    M1 = 1.00:0.01:5.00;
                    a = (gamma-1).*M1.*M1+2;
                    b = 2.*gamma.* M1.* M1-gamma+1;
                    M2 = sqrt((a./b));
                    T_ratio = (b.*(2+(gamma-1).*M1.*M1))./((gamma+1).*(gamma+1).*M1.*M1);
                    rho_ratio = ((gamma+1).*M1.*M1)./(2+(gamma-1).*M1.*M1);
                    P_ratio = rho_ratio.*T_ratio;
                    P02_P1=((((gamma+1)./2).*M1.*M1).^(gamma./(gamma-1))).*(((2.*gamma.*M1.*M1-gamma+1)./(gamma+1)).^((-1./(gamma-1))));

                    P02_P01 = (((1+(gamma-1)./2.*M2.^2)./(1+(gamma-1)./2.*M1.^2)).^(gamma./(gamma-1)).*(((2.*gamma.*M1.^2-(gamma-1))./(gamma+1))));
                    t=table(M1',M2',P_ratio',T_ratio',rho_ratio',P02_P01',P02_P1');
                    app.UITable2.Data=t;
                    app.UITable2.ColumnName={'M1','M2','P2/P1','T2/T1','rho2/rho1','P02/P01','P02/P1'};
            end
            
        end

        % Button pushed function: findButton
        function findButtonPushed(app, event)
            value=app.table.Value;
            switch value
                case 'Isentropic tables for gamma=1.4'
                    M=app.MachEditField.Value;
                    
                    % table=app.UITable.Data;
                    % if app.MachEditField.Value~=0
                    %     roundedMach= round(app.MachEditField.Value, 2);
                    %     index = find(round(app.MachEditField.Value, 2) == roundedMach);
                    %     if isempty(index)
                    %         disp('Value not found in the table.');
                    %     else
                    %         app.ToTEditField.Value=table(num2str(T0_T(index)));
                    %         app.PoPEditField.Value=table(num2str(P0_p(index)));
                    %         app.Rho0rhoEditField.Value=table(num2str(rho0_rho(index)));
                    %         app.aoaEditField.Value=table(num2str(a0_a(index)));
                    %         app.AAEditField.Value=table(num2str(A_Astar(index)));
                    % 
                    %     end
                    % end
                    gamma=1.4;
                    T0_T = 1 + (gamma - 1) ./ 2 .* M.^2;
                    P0_P = (1 + (gamma - 1) ./ 2 .* M.^2).^(gamma ./ (gamma - 1));
                    rho0_rho = (1 + (gamma - 1) ./ 2 .* M.^2).^(1 ./ (gamma - 1));
                    a0_a = (1 + (gamma - 1) ./ 2 .* M.^2).^1/2;
                    A_Astar = 1 ./ M .* (2 ./ (gamma + 1) .* (1 + (gamma - 1) / 2 .* M.^2)).^((gamma + 1) ./ (2 .* (gamma - 1)));
                    app.ToTEditField.Value=T0_T;
                    app.PoPEditField.Value=P0_P;
                    app.Rho0rhoEditField.Value=rho0_rho;
                    app.aoaEditField.Value=a0_a;
                    app.AAEditField.Value=A_Astar;

                case 'Normal shock tables for gamma=1.4'
                    M1=app.MachEditField.Value;
                    gamma = 1.4;
                    a = (gamma-1).*M1.*M1+2;
                    b = 2.*gamma.* M1.* M1-gamma+1;
                    M2 = sqrt((a./b));
                    T_ratio = (b.*(2+(gamma-1).*M1.*M1))./((gamma+1).*(gamma+1).*M1.*M1);
                    rho_ratio = ((gamma+1).*M1.*M1)./(2+(gamma-1).*M1.*M1);
                    P_ratio = rho_ratio.*T_ratio;
                    P02_P01 = (((1+(gamma-1)./2.*M2.^2)./(1+(gamma-1)./2.*M1.^2)).^(gamma./(gamma-1)).*(((2.*gamma.*M1.^2-(gamma-1))./(gamma+1))));
                    P02_P1=((((gamma+1)./2).*M1.*M1).^(gamma./(gamma-1))).*(((2.*gamma.*M1.*M1-gamma+1)./(gamma+1)).^((-1./(gamma-1))));
                    app.M2EditField.Value=M2;
                    app.P2P1EditField_2.Value=P_ratio;
                    app.T2T1EditField.Value=T_ratio;
                    app.rho2rho1EditField.Value=rho_ratio;
                    app.P02P01EditField.Value=P02_P01;
                    app.P02P1EditField.Value=P02_P1;


            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.compressibleflowcalculatorUIFigure = uifigure('Visible', 'off');
            app.compressibleflowcalculatorUIFigure.Name = 'compressible flow calculator';
            app.compressibleflowcalculatorUIFigure.Icon = fullfile(pathToMLAPP, 'gas frame moving.png');
            app.Menu = uimenu(app.compressibleflowcalculatorUIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.compressibleflowcalculatorUIFigure);
            app.Menu2.Text = 'Menu2';
            app.INPUTLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.INPUTLabel.Text = 'INPUT';
            app.T1EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.P1EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.P2P1Label = uilabel(app.compressibleflowcalculatorUIFigure);
            app.P2P1Label.Text = 'P2/P1';
            app.P2P1EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.machshock = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.gammaLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.gammaLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.REditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.OUTPUTLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.OUTPUTLabel.Text = 'OUTPUT';
            app.MsEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.P2EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.P2EditFieldLabel.Text = 'P2';
            app.P2EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.Machv2 = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.Switch = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.Switch.Items = {'M1', 'V1'};
            app.Switch.Value = 'M1';
            app.Switch_2 = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.Switch_2.Items = {'M2', 'V2'};
            app.Switch_2.Value = 'M2';
            app.computeButton = uibutton(app.compressibleflowcalculatorUIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.M1V1 = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.M2V2 = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.Switch_3 = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.Switch_3.Items = {'Ms', 'Vs'};
            app.Switch_3.Value = 'Ms';
            app.Switch2 = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.Switch2.Items = {'Ms', 'Vs'};
            app.Switch2.Value = 'Ms';
            app.Switch2_2 = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.Switch2_2.Items = {'M2', 'V2'};
            app.Switch2_2.Value = 'M2';
            app.T2EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.T2EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.T2EditFieldLabel.Text = 'T2';
            app.Image = uiimage(app.compressibleflowcalculatorUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gas frame moving.png');
            app.Image2 = uiimage(app.compressibleflowcalculatorUIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2023-07-04 234959.png');
            app.P3EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.P3EditFieldLabel.Visible = 'off';
            app.P3EditFieldLabel.Text = 'P3';
            app.P3EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.P3EditField.Visible = 'off';
            app.T3EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.T3EditFieldLabel.Visible = 'off';
            app.T3EditFieldLabel.Text = 'T3';
            app.T3EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.T3EditField.Visible = 'off';
            app.M2rV2r = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.M2rV2r.Items = {'M2R_p', 'V2r_p'};
            app.M2rV2r.Visible = 'off';
            app.M2rV2r.Value = 'M2R_p';
            app.Machref = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.Machref.Visible = 'off';
            app.M3pV3p = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.M3pV3p.Items = {'M3_p', 'V3_p'};
            app.M3pV3p.Visible = 'off';
            app.M3pV3p.Value = 'M3_p';
            app.Mach3p = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.Mach3p.Visible = 'off';
            app.TypeofnormalmovingshockwaveSwitchLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.TypeofnormalmovingshockwaveSwitchLabel.Text = 'Type of normal moving shock wave';
            app.TypeofnormalmovingshockwaveSwitch = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.TypeofnormalmovingshockwaveSwitch.Items = {'normal', 'reflected'};
            app.TypeofnormalmovingshockwaveSwitch.ValueChangedFcn = createCallbackFcn(app, @TypeofnormalmovingshockwaveSwitchValueChanged, true);
            app.TypeofnormalmovingshockwaveSwitch.Value = 'normal';
            app.UITable = uitable(app.compressibleflowcalculatorUIFigure);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.table = uiswitch(app.compressibleflowcalculatorUIFigure, 'slider');
            app.table.Items = {'Isentropic tables for gamma=1.4', 'Normal shock tables for gamma=1.4'};
            app.table.ValueChangedFcn = createCallbackFcn(app, @tableValueChanged, true);
            app.table.Value = 'Isentropic tables for gamma=1.4';
            app.UITable2 = uitable(app.compressibleflowcalculatorUIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'};
            app.UITable2.RowName = {};
            app.UITable2.Visible = 'off';
            app.MachEditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.MachEditFieldLabel.Text = 'Mach';
            app.MachEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.Rho0rhoEditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.Rho0rhoEditFieldLabel.Text = 'Rho0/rho';
            app.Rho0rhoEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.ToTEditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.ToTEditFieldLabel.Text = 'To/T';
            app.ToTEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.PoPEditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.PoPEditFieldLabel.Text = 'Po/P';
            app.PoPEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.aoaEditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.aoaEditFieldLabel.Text = 'ao/a';
            app.aoaEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.AAEditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.AAEditFieldLabel.Text = 'A/A*';
            app.AAEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.findButton = uibutton(app.compressibleflowcalculatorUIFigure, 'push');
            app.findButton.ButtonPushedFcn = createCallbackFcn(app, @findButtonPushed, true);
            app.findButton.Text = 'find';
            app.M2EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.M2EditFieldLabel.Visible = 'off';
            app.M2EditFieldLabel.Text = 'M2';
            app.M2EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.M2EditField.Visible = 'off';
            app.P2P1EditField_2Label = uilabel(app.compressibleflowcalculatorUIFigure);
            app.P2P1EditField_2Label.Visible = 'off';
            app.P2P1EditField_2Label.Text = 'P2/P1';
            app.P2P1EditField_2 = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.P2P1EditField_2.Visible = 'off';
            app.T2T1EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.T2T1EditFieldLabel.Visible = 'off';
            app.T2T1EditFieldLabel.Text = 'T2/T1';
            app.T2T1EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.T2T1EditField.Visible = 'off';
            app.rho2rho1EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.rho2rho1EditFieldLabel.Visible = 'off';
            app.rho2rho1EditFieldLabel.Text = 'rho2/rho1';
            app.rho2rho1EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.rho2rho1EditField.Visible = 'off';
            app.P02P01EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.P02P01EditFieldLabel.Visible = 'off';
            app.P02P01EditFieldLabel.Text = 'P02/P01';
            app.P02P01EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.P02P01EditField.Visible = 'off';
            app.P02P1EditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.P02P1EditFieldLabel.Visible = 'off';
            app.P02P1EditFieldLabel.Text = 'P02/P1';
            app.P02P1EditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.P02P1EditField.Visible = 'off';
            app.computetimesEditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.computetimesEditFieldLabel.Text = 'compute time (s)';
            app.computetimesEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            app.iterationdoneEditFieldLabel = uilabel(app.compressibleflowcalculatorUIFigure);
            app.iterationdoneEditFieldLabel.Text = 'iteration done';
            app.iterationdoneEditField = uieditfield(app.compressibleflowcalculatorUIFigure, 'numeric');
            % Show the figure after all components are created
            app.compressibleflowcalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LINA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.compressibleflowcalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.compressibleflowcalculatorUIFigure)
        end
    end
end